classdef Heat_Exchanger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CalculateButton                 matlab.ui.control.Button
        ArrangementSquare1Triangle2Label  matlab.ui.control.Label
        Angle90604530Label              matlab.ui.control.Label
        ArrangeEditField                matlab.ui.control.NumericEditField
        ArrangeEditFieldLabel           matlab.ui.control.Label
        AngleEditField                  matlab.ui.control.NumericEditField
        AngleEditFieldLabel             matlab.ui.control.Label
        DeltaPressureEditField_2        matlab.ui.control.NumericEditField
        DeltaPressureEditField_2Label   matlab.ui.control.Label
        DeltaPressureEditField          matlab.ui.control.NumericEditField
        DeltaPressureEditFieldLabel     matlab.ui.control.Label
        NumberofBaffleNbEditField       matlab.ui.control.NumericEditField
        NumberofBaffleNbEditFieldLabel  matlab.ui.control.Label
        BaffleDistancemEditField        matlab.ui.control.NumericEditField
        BaffleDistancemEditFieldLabel   matlab.ui.control.Label
        MassFlowRatekgsEditField        matlab.ui.control.NumericEditField
        MassFlowRatekgsEditFieldLabel   matlab.ui.control.Label
        VelocitymsEditField             matlab.ui.control.NumericEditField
        VelocitymsEditFieldLabel        matlab.ui.control.Label
        FluidViscositymEditField_2      matlab.ui.control.NumericEditField
        FluidViscositymEditField_2Label  matlab.ui.control.Label
        FluidDensityKgm3EditField_2     matlab.ui.control.NumericEditField
        FluidDensityKgm3EditField_2Label  matlab.ui.control.Label
        PitchTubeEditField              matlab.ui.control.NumericEditField
        PitchTubeEditFieldLabel         matlab.ui.control.Label
        PipeThicknessmEditField_2       matlab.ui.control.NumericEditField
        PipeThicknessmEditField_2Label  matlab.ui.control.Label
        NumberofPassNEditField          matlab.ui.control.NumericEditField
        NumberofPassNEditFieldLabel     matlab.ui.control.Label
        PipeLengthmEditField            matlab.ui.control.NumericEditField
        PipeLengthmEditFieldLabel       matlab.ui.control.Label
        InnerDiametermEditField         matlab.ui.control.NumericEditField
        InnerDiametermEditFieldLabel    matlab.ui.control.Label
        FluidViscositymEditField        matlab.ui.control.NumericEditField
        FluidViscositymEditFieldLabel   matlab.ui.control.Label
        FluidDensityKgm3EditField       matlab.ui.control.NumericEditField
        FluidDensityKgm3EditFieldLabel  matlab.ui.control.Label
        ShellSetupLabel                 matlab.ui.control.Label
        TubeSetupLabel                  matlab.ui.control.Label
        KERNMETHODLabel                 matlab.ui.control.Label
        ShellTubeHeatExchangerLabel     matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %% Calculation
            %% Properties
density_tube=app.DeltaPressureEditField.Value; %Density (kg/m3)
density_shell=app.DeltaPressureEditField_2.Value ; %
vis_t=app.FluidViscositymEditField.Value;       %Viscosity 
vis_s=app.FluidDensityKgm3EditField_2.Value;
di_t=app.InnerDiametermEditField.Value;              %Inter diameter of tube
d0_t=app.PipeThicknessmEditField_2.Value;           %Outer diameter of tube
u=app.VelocitymsEditField.Value;                  % Inlet Velocity
L=app.PipeLengthmEditField.Value;                   % Tube Length
N=app.NumberofPassNEditField.Value;                    % Number of tube pass
degree=app.AngleEditField.Value;         %Angle for tube
PT=app.PitchTubeEditField.Value;                 %Pitch Tube
m_s=app.MassFlowRatekgsEditField.Value;             % shell-side mass flow rate
B=app.BaffleDistancemEditField.Value;    %Baffle Spacing
%Type of arrangement
arrangment=app.ArrangeEditField.Value;  % Square =1 Triangle= else
Nb=app.NumberofBaffleNbEditField.Value;

%% Tube Calculation

Re_tube=(density_tube*u*di_t)./vis_t;
f_t=(1.58*log(Re_tube)-3.28)^-2; 
delta_p_tube=(((4*f_t*L*N)/di_t+4*N)*(vis_t*u^2))/2;


%% Shell Calculation
if N==1
    CTP=0.93;
elseif N==2
    CTP=0.90;
else
    CTP=0.85;
end
if degree== 90 || degree==45
    CL=1.0;
elseif degree==30 || degree==60
    CL=0.87;
end

PR=PT/d0_t;
A0=pi*d0_t*N*L;
Ds=0.631*sqrt(CL/CTP)*sqrt((A0*PR^2*d0_t)/L);
if arrangment==1
De=4*(PT^2-(pi*d0_t^2)/4)/(pi*d0_t);                      %Square arrangment
elseif arrangment==2 
De=(4*((PT^2*sqrt(3))/4-(pi*d0_t^2)/8))/(pi*d0_t/2); %Triangle Arrangmene
else
    
end
C=PT-d0_t;    %Clearance

As=(Ds*C*B)/PT;
Gs=m_s/As;
Re_shell=(Gs*De)/vis_s;
f_s=exp(0.576-0.19*log(Re_shell));
sigma_s=(vis_s/vis_t)^0.14;
delta_p_shell=(f_s*Gs^2*(Nb+1)*Ds)/(2*density_shell*De*sigma_s);


%%Result
%%fprintf('Pressure Drop in tube: %0.5f\n',delta_p_tube);
%%fprintf('Pressure Drop in Shell: %0.5f\n',delta_p_shell);
app.DeltaPressureEditField.Value=delta_p_tube;
app.DeltaPressureEditField_2.Value=delta_p_shell;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ShellTubeHeatExchangerLabel = uilabel(app.UIFigure);
            app.ShellTubeHeatExchangerLabel.Text = 'Shell-Tube Heat Exchanger';
            app.KERNMETHODLabel = uilabel(app.UIFigure);
            app.KERNMETHODLabel.Text = 'KERN METHOD';
            app.TubeSetupLabel = uilabel(app.UIFigure);
            app.TubeSetupLabel.Text = 'Tube Setup';
            app.ShellSetupLabel = uilabel(app.UIFigure);
            app.ShellSetupLabel.Text = 'Shell Setup';
            app.FluidDensityKgm3EditFieldLabel = uilabel(app.UIFigure);
            app.FluidDensityKgm3EditFieldLabel.Text = 'Fluid Density (Kg/m3)';
            app.FluidDensityKgm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.FluidDensityKgm3EditField.RoundFractionalValues = 'on';
            app.FluidViscositymEditFieldLabel = uilabel(app.UIFigure);
            app.FluidViscositymEditFieldLabel.Text = 'Fluid Viscosity (m)';
            app.FluidViscositymEditField = uieditfield(app.UIFigure, 'numeric');
            app.InnerDiametermEditFieldLabel = uilabel(app.UIFigure);
            app.InnerDiametermEditFieldLabel.Text = 'Inner Diameter (m)';
            app.InnerDiametermEditField = uieditfield(app.UIFigure, 'numeric');
            app.PipeLengthmEditFieldLabel = uilabel(app.UIFigure);
            app.PipeLengthmEditFieldLabel.Text = 'Pipe Length (m)';
            app.PipeLengthmEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofPassNEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofPassNEditFieldLabel.Text = 'Number of Pass (N)';
            app.NumberofPassNEditField = uieditfield(app.UIFigure, 'numeric');
            app.PipeThicknessmEditField_2Label = uilabel(app.UIFigure);
            app.PipeThicknessmEditField_2Label.Text = 'Pipe Thickness (m)';
            app.PipeThicknessmEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.PipeThicknessmEditField_2.Value = 0.0005;
            app.PitchTubeEditFieldLabel = uilabel(app.UIFigure);
            app.PitchTubeEditFieldLabel.Text = 'Pitch Tube';
            app.PitchTubeEditField = uieditfield(app.UIFigure, 'numeric');
            app.FluidDensityKgm3EditField_2Label = uilabel(app.UIFigure);
            app.FluidDensityKgm3EditField_2Label.Text = 'Fluid Density (Kg/m3)';
            app.FluidDensityKgm3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.FluidViscositymEditField_2Label = uilabel(app.UIFigure);
            app.FluidViscositymEditField_2Label.Text = 'Fluid Viscosity (m)';
            app.FluidViscositymEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.VelocitymsEditFieldLabel = uilabel(app.UIFigure);
            app.VelocitymsEditFieldLabel.Text = 'Velocity (m/s)';
            app.VelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MassFlowRatekgsEditFieldLabel = uilabel(app.UIFigure);
            app.MassFlowRatekgsEditFieldLabel.Text = 'Mass Flow Rate (kg/s)';
            app.MassFlowRatekgsEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaffleDistancemEditFieldLabel = uilabel(app.UIFigure);
            app.BaffleDistancemEditFieldLabel.Text = 'Baffle Distance (m)';
            app.BaffleDistancemEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofBaffleNbEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofBaffleNbEditFieldLabel.Text = 'Number of Baffle  (Nb)';
            app.NumberofBaffleNbEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeltaPressureEditFieldLabel = uilabel(app.UIFigure);
            app.DeltaPressureEditFieldLabel.Text = 'Delta Pressure';
            app.DeltaPressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeltaPressureEditField_2Label = uilabel(app.UIFigure);
            app.DeltaPressureEditField_2Label.Text = 'Delta Pressure';
            app.DeltaPressureEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ArrangeEditFieldLabel = uilabel(app.UIFigure);
            app.ArrangeEditFieldLabel.Text = 'Arrange';
            app.ArrangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.Angle90604530Label = uilabel(app.UIFigure);
            app.Angle90604530Label.Text = 'Angle: 90,60,45,30';
            app.ArrangementSquare1Triangle2Label = uilabel(app.UIFigure);
            app.ArrangementSquare1Triangle2Label.Text = 'Arrangement: Square=1,Triangle=2';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Heat_Exchanger

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end